classdef DataAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataAnalysis_R                  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DisplayControlPanel             matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        StartSpinnerLabel               matlab.ui.control.Label
        StartTimeSpinner                matlab.ui.control.Spinner
        DurationSpinnerLabel            matlab.ui.control.Label
        PlotdurationSpinner             matlab.ui.control.Spinner
        StdOverSpinnerLabel             matlab.ui.control.Label
        nStdSpinner                     matlab.ui.control.Spinner
        ModenumDropDownLabel            matlab.ui.control.Label
        ModenumberDropDown              matlab.ui.control.DropDown
        PlotdetrendSwitchLabel          matlab.ui.control.Label
        PlotdetrendeddataSwitch         matlab.ui.control.Switch
        WinsizeSpinnerLabel             matlab.ui.control.Label
        WinsizeSpinner                  matlab.ui.control.Spinner
        PeakAnalysisPanel               matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        FilterPropertiesPanel           matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        FiltertypeLabel                 matlab.ui.control.Label
        FiltertypeDropDown              matlab.ui.control.DropDown
        WindowSizeSpinnerLabel          matlab.ui.control.Label
        WindowsizeSpinner               matlab.ui.control.Spinner
        FilterdataButton                matlab.ui.control.Button
        Plotfiltered                    matlab.ui.control.Switch
        LambdapropertiesPanel           matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        AmpThreshSpinnerLabel           matlab.ui.control.Label
        Plot1Ampthreshold               matlab.ui.control.Spinner
        SmoothWiidthSpinnerLabel        matlab.ui.control.Label
        Plot1SmoothWidth                matlab.ui.control.Spinner
        GroupWidthSpinnerLabel          matlab.ui.control.Label
        Plot1GroupWidth                 matlab.ui.control.Spinner
        SlopeThreshSpinnerLabel         matlab.ui.control.Label
        Plot1Slopethreshold             matlab.ui.control.Spinner
        SmoothtypeDropDownLabel         matlab.ui.control.Label
        Plot1Smoothtype                 matlab.ui.control.DropDown
        StdDeviationEditFieldLabel      matlab.ui.control.Label
        Plot1stdDisp                    matlab.ui.control.NumericEditField
        FWHMpropertiesPanel             matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        StdDeviationEditField_2Label    matlab.ui.control.Label
        Plot2stdDisp                    matlab.ui.control.NumericEditField
        AmpThreshSpinner_2Label         matlab.ui.control.Label
        Plot2Ampthreshold               matlab.ui.control.Spinner
        SmoothtypeDropDown_2Label       matlab.ui.control.Label
        Plot2Smoothtype                 matlab.ui.control.DropDown
        SmoothWiidthSpinner_2Label      matlab.ui.control.Label
        Plot2SmoothWidth                matlab.ui.control.Spinner
        GroupWidthSpinner_2Label        matlab.ui.control.Label
        Plot2GroupWidth                 matlab.ui.control.Spinner
        SlopeThreshSpinner_2Label       matlab.ui.control.Label
        Plot2Slopethreshold             matlab.ui.control.Spinner
        FinalizepeaksPanel              matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        FindPeaksButton                 matlab.ui.control.Button
        AddPlot1PeakButton              matlab.ui.control.Button
        AddPlot2PeakButton              matlab.ui.control.Button
        GridLayout13                    matlab.ui.container.GridLayout
        NegativeSwitchLabel             matlab.ui.control.Label
        ReGrouppeaksButton              matlab.ui.control.Button
        AddspecialPeaksButton           matlab.ui.control.Button
        GroupwithinmsLabel              matlab.ui.control.Label
        NegativepeaksSwitch             matlab.ui.control.Switch
        DurationThresholdSpinner        matlab.ui.control.Spinner
        PeakfindingmethodDropDownLabel  matlab.ui.control.Label
        PeakDetectionMethodDropDown     matlab.ui.control.DropDown
        StepDetectionPanel              matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        StartSpinner_2Label             matlab.ui.control.Label
        startPointSpinner               matlab.ui.control.Spinner
        LOSpinnerLabel                  matlab.ui.control.Label
        leftOffsetSpinner               matlab.ui.control.Spinner
        ROSpinnerLabel                  matlab.ui.control.Label
        rightOffsetSpinner              matlab.ui.control.Spinner
        LILabel                         matlab.ui.control.Label
        leftIntervalSpinner             matlab.ui.control.Spinner
        RISpinnerLabel                  matlab.ui.control.Label
        rightIntervalSpinner            matlab.ui.control.Spinner
        Plot1fmEditFieldLabel           matlab.ui.control.Label
        plot1heightfmEditField          matlab.ui.control.NumericEditField
        Plot2fmEditFieldLabel           matlab.ui.control.Label
        plot2heightfmEditField          matlab.ui.control.NumericEditField
        AddStepPlot1Button              matlab.ui.control.Button
        AddStepPlot2Button              matlab.ui.control.Button
        FIleIOPanel                     matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        OpenButton                      matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        ClearvariablesButton            matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        FilepathTextAreaLabel           matlab.ui.control.Label
        FilepathTextArea                matlab.ui.control.TextArea
        GridLayout7                     matlab.ui.container.GridLayout
        CommentTextAreaLabel            matlab.ui.control.Label
        CommentTextArea                 matlab.ui.control.TextArea
        GridLayout15                    matlab.ui.container.GridLayout
        SaveplotsButton                 matlab.ui.control.Button
        plot2                           matlab.ui.control.UIAxes
        plot1                           matlab.ui.control.UIAxes
        steph                           matlab.ui.control.UIAxes
        peakd                           matlab.ui.control.UIAxes
        peakh                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
          % File name and path name
        fileProps = struct('filename', [], 'pathname',[],'FileInfo',struct([]),'ext','.txt','oldFolder',[]);
        
        % Data from text file or tdms files
        iData = struct('time',[],'plot1',[],'dt_plot1',[],'plot2',[],'dt_plot2',[],...
            'modeNumber',1,'tTime',100,'comment','','sTime',0,'eTime',20,'sIndex',1,...
            'eIndex',1000,'duration',20,'samplingTime',0.0192, 'numModes',1);
        
        % Properties of analysis (rate measurements and peka height histograms)
        aData = struct('duration',[],'locs',[],'peakHeights',[],'conc',1,'concVsRate',[],...
            'onRate',0,'offRate',0);
        
        % Detrend properties for analysing swData
        fData = struct('filterType','Golay','winSize',101);
        
        % Properties of the peak finder
        peakFinder = struct('N',10,'sdevplot1',0,'sdevplot2',0,'ampThresholdplot1',3,...
            'ampThresholdplot2',3,'slopeThresholdplot1',1e-4,'slopeThresholdplot2',1e-4,...
            'smoothWidthplot1',101','smoothWidthplot2',101,'smoothTypeplot1',3,...
            'smoothTypeplot2',3,'thresholdDur',0.05,'groupWidthplot1',200,...
            'groupWidthplot2',200,'nflag','Off');
        
        % Properties of the step finder
        stepFinder = struct('startPoint',100,'leftInterval',10,'leftOffset',0,...
            'rightInterval',10,'rightOffset',1,...
            'steps', struct('time',[],'height',[],'width',[]),...
            'T_plot1',[],'H_plot1',[],'W_plot1',[],'T_plot2',[],'H_plot2',[],'W_plot2',[]);
        
        % structures to the stores resulting peak information
        rData = struct('plot1Peaks',struct('h',[],'l',[],'w',[],'p',[],'area',[]),...
            'plot2Peaks',struct('h',[],'l',[],'w',[],'p',[],'area',[]),...
            'plot1PeaksF',struct([]),'plot2PeaksF',struct([]),...
            'sPeaks1F',struct([]),'sPeaks2F',struct([]),...
            'plot1StepsF',struct('t',[],'h',[],'w',[],'numSteps',[],'leftOffset',[],'rightOffset',[]),...
            'plot2StepsF',struct('t',[],'h',[],'w',[],'numSteps',[],'leftOffset',[],'rightOffset',[]) );
        
        % plot properties
        plotProps = struct('dToggle','Off','fToggle','Off','c_axes',[],'titles',...
            {{'plot1','plot2'}},'winsize',101,'xlims',[0 20]);
        
        % temp variables
        tmp = struct('plot1Peaks',struct('h',[],'l',[],'w',[],'p',[],'area',[]),...
            'plot2Peaks',struct('h',[],'l',[],'w',[],'p',[],'area',[]),'plot1PeaksF',struct([]),...
            'plot2PeaksF',struct([]));
        
        % Error handling structures
        errmsg = '';% error message
    end
    
    methods (Access = private)
        
        % Function to assign the properties on one structure into an internal variable property
        function assignToStruct(app,oStruct,iStruct)
            names = fieldnames(iStruct);
            for i = 1:length(names)
                app.(oStruct).(names{i}) = iStruct.(names{i});
            end
        end
        
        
        % Disable controls at startup or while executing certain parts of the code
        function disablecontrols(app)
            app.StartTimeSpinner.Enable = 'Off';
            app.PlotdurationSpinner.Enable = 'Off';
            app.nStdSpinner.Enable = 'Off';
            app.ModenumberDropDown.Enable = 'Off';
            app.PeakAnalysisPanel.Visible = 'Off';
            app.StepDetectionPanel.Visible = 'Off';
            app.SaveDataButton.Enable = 'Off';
        end
        
        % Enable controls after succesful loading of data or analysis
        function enablecontrols(app)
            app.StartTimeSpinner.Enable = 'On';
            app.PlotdurationSpinner.Enable = 'On';
            app.nStdSpinner.Enable = 'On';
            app.ModenumberDropDown.Enable = 'On';
            app.PeakAnalysisPanel.Visible = 'On';
            app.StepDetectionPanel.Visible = 'On';
            app.SaveDataButton.Enable = 'On';
        end
        
        % resets all the data structures to initial values
        function resetvariables(app)
            names = fieldnames(app.rData);
            for i = 1:length(names)
                if contains(names{i},'F')
                    app.rData.(names{i}) = struct([]);
                else
                    app.rData.(names{i}) = struct('h',[],'l',[],'w',[],'p',[],'area',[]);
                end
            end
            h = findobj(app.DataAnalysis_R,'Type','axes');
            for i = 1:length(h)
                cla(h(i));
            end
        end
        
        % Gets data from the text or TDMS files and plots them
        function fetchdata(app)
            try
                [results, err] = getData(app.fileProps,app.iData.sTime,app.iData.eTime,app.iData.Fs);
                assignToStruct(app,'iData',results);
                if ~isempty(err)
                    printerror(app,err);
                    return
                end
                [results, err] = filterdata(app.iData,{'plot1','plot2'},app.fData.filterType,app.fData.winSize);
                if ~isempty(err)
                    printerror(app,err);
                    return
                end
                assignToStruct(app,'iData',results);
                app.plotProps.winsize = app.WinsizeSpinner.Value;
                if strcmpi(app.plotProps.fToggle,'on')
                    results = detrendData(app.iData,{'fplot1','fplot2'},app.plotProps.winsize);
                else
                    results = detrendData(app.iData,{'plot1','plot2'},app.plotProps.winsize);
                end
                if ~isempty(err)
                    printerror(app, err);
                    return
                end
                assignToStruct(app,'iData',results);
%                 results = detrendData(app.iData,{'fplot1','fplot2'});
%                 assignToStruct(app,'iData',results);
                if app.iData.tTime-app.iData.duration > 0
                    app.StartTimeSpinner.Limits = [0 app.iData.tTime-app.iData.duration];
                end
                app.PlotdurationSpinner.Limits = [1/app.iData.Fs app.iData.tTime-app.iData.sIndex];
                plotdata([app.plot1,app.plot2], app.iData, app.plotProps.dToggle,...
                    app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
                nStdSpinnerValueChanged(app,nan);
                for i = 1:length(app.iData.plot1(1,:))
                    app.ModenumberDropDown.Items(i) = {num2str(i)};
                end
                app.plotProps.titles = app.iData.plotTitles;
                app.plot1.Title.String = app.plotProps.titles{1};
                app.plot2.Title.String = app.plotProps.titles{2};
            catch err
                printerror(app, err);
            end
        end
        
        
        function peakfinder(app)
            
            % Sets the data for finding peaks and calls the findpeaksindata function
            
            plotdata([app.plot1,app.plot2], app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2] );
            nm = app.iData.modeNumber;
            xdata = app.iData.time;
            if strcmpi(app.plotProps.dToggle,'On')
                if strcmpi(app.plotProps.fToggle,'On')
                    fields = {'dt_fplot1','dt_fplot2'};
                else
                    fields = {'dt_plot1','dt_plot2'};
                end
            else
                if strcmpi(app.plotProps.fToggle,'On')
                    fields = {'fplot1','fplot2'};
                else
                    fields = {'plot1','plot2'};
                end
            end
            for i = 1:length(fields)
                ydata = app.iData.(fields{i})(:,nm);
                if strcmpi(app.PeakDetectionMethodDropDown.Value,'FindPeaks Matlab')
                    [oStruct.(['plot',num2str(i),'Peaks']),err] = findpeaksindata(ydata, xdata,...
                        app.peakFinder.(['sdevplot',num2str(i)]),app.nStdSpinner.Value,...
                        app.peakFinder.(['ampThresholdplot',num2str(i)]),app.peakFinder.nflag);
                else
                    [oStruct.(['plot',num2str(i),'Peaks']),err] = findpeaksindata2(ydata, xdata,...
                        app.peakFinder.(['slopeThresholdplot',num2str(i)]),app.peakFinder.(['ampThresholdplot',num2str(i)]),...
                        app.peakFinder.(['smoothWidthplot',num2str(i)]), app.peakFinder.(['groupWidthplot',num2str(i)]),...
                        app.peakFinder.(['smoothTypeplot',num2str(i)]),app.peakFinder.nflag);
                end
                if ~isempty(err)
                    printerror(app, err);
                    return
                end
                peakfields = fieldnames(oStruct.(['plot',num2str(i),'Peaks']));
                if ~isempty(oStruct.(['plot',num2str(i),'Peaks']).l)
                    [~,I] = sort(oStruct.(['plot',num2str(i),'Peaks']).l);
                    for j = 1:length(peakfields)
                        oStruct.(['plot',num2str(i),'Peaks']).(peakfields{j}) = oStruct.(['plot',num2str(i),'Peaks']).(peakfields{j})(I);
                    end
                    [oStruct.(['plot',num2str(i),'PeaksF']),err] = grouppeaks(oStruct.(['plot',num2str(i),'Peaks']),...
                        app.peakFinder.thresholdDur);
                    if ~isempty(err)
                        printerror(app, err);
                        return
                    end
                    assignToStruct(app,'tmp',oStruct);
                    if ~isempty(app.tmp.(['plot',num2str(i),'Peaks']).l)
                        err = plotpeaks(app.(['plot',num2str(i)]),app.iData, app.tmp.(['plot',num2str(i),'PeaksF']),fields{i});
                        if ~isempty(err)
                            printerror(app, err);
                            return
                        end
                        err = plotsubpeaks(app.(['plot',num2str(i)]),app.iData, app.tmp.(['plot',num2str(i),'Peaks']),fields{i});
                        if ~isempty(err)
                            printerror(app, err);
                            return
                        end
                    end
                end
            end
        end
        
        function printerror(app, err)
            %% Prints the error message as contained in 'err' in a UI errormsg dialog box
            
            title = err.identifier(8:end);
            uialert(app.DataAnalysis_R,err.message,title);
            enablecontrols(app);
        end
        
        
        function findsteps(app)
            %% Function to find the steps in the data displayed in each window
            nm = app.iData.modeNumber;
            if strcmpi(app.plotProps.dToggle,'On')
                if strcmpi(app.plotProps.fToggle,'On')
                    fields = {'dt_fplot1','dt_fplot2'};
                else
                    fields = {'dt_plot1','dt_plot2'};
                end
            else
                if strcmpi(app.plotProps.fToggle,'On')
                    fields = {'fplot1','fplot2'};
                else
                    fields = {'plot1','plot2'};
                end
            end
            for i =1:length(fields)
                y = app.iData.(fields{i})(:,nm)';
                time = ['T_',fields{i}];
                height = ['H_',fields{i}];
                width = ['W_',fields{i}];
                [app.stepFinder.(time), app.stepFinder.(height), app.stepFinder.(width), err] = stepDetect(app.(['plot',num2str(i)]), y,app.iData.time, app.stepFinder);
                if ~isempty(err)
                    printerror(app, err);
                    return
                end
                app.(['plot',num2str(i),'heightfmEditField']).Value = app.stepFinder.(height);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.iData.modeNumber = str2double(app.ModenumberDropDown.Value);
            app.peakFinder.thresholdDur = app.DurationThresholdSpinner.Value/1000;
            app.fData.winSize = app.WindowsizeSpinner.Value;
            app.plotProps.nflag = app.NegativepeaksSwitch.Value;
            app.peakFinder.threholdplot1 = app.Plot1Ampthreshold.Value;
            app.peakFinder.threholdplot2 = app.Plot2Ampthreshold.Value;
            app.StartTimeSpinner.Step = app.PlotdurationSpinner.Value;
            app.plotProps.xlims = [app.iData.sTime app.iData.eTime];
            app.iData.modeNumber = 1;
            app.StartTimeSpinner.Value = 1;
            app.iData.sTime = 1;
            app.iData.eTime = app.iData.sTime + app.PlotdurationSpinner.Value;
            app.ModenumberDropDown.Items = {'1'};
            resetvariables(app);
            disablecontrols(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            startupFcn(app);
            resetvariables(app);
           
            [app.fileProps.filename, app.fileProps.pathname] =...
                uigetfile({'*.txt';'*.tdms'},'File Open Dialog');
            if app.fileProps.filename
                app.fileProps.oldFolder = cd(app.fileProps.pathname);
                fileprops = setfileProperties(fullfile(app.fileProps.pathname,...
                    app.fileProps.filename));
                assignToStruct(app,'fileProps',fileprops);
                results = getHeaders(app.fileProps);
                assignToStruct(app,'iData',results);
                fetchdata(app);
                app.FilepathTextArea.Value = fullfile(app.fileProps.pathname,...
                    app.fileProps.filename);
                app.CommentTextArea.Value = app.iData.comment;
                enablecontrols(app);
                %app.plotProps.titles = inputdlg({'Plot1','Plot2'},'Plot titles',1,{'lambda','fwhm'});
%                 if strcmpi(app.fileProps.ext,'.tdms')
%                     app.SelectChannelPlot1.Enable = true;
%                     app.SelectChannelPlot2.Enable = true;
%                 end
                app.Plot1GroupWidth.Enable = false;
                app.Plot1SmoothWidth.Enable = false;    
                app.Plot1Smoothtype.Enable = false;
                app.Plot1Slopethreshold.Enable = false;
                app.Plot2GroupWidth.Enable = false;
                app.Plot2SmoothWidth.Enable = false;
                app.Plot2Smoothtype.Enable = false;
                app.Plot2Slopethreshold.Enable = false;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.DataAnalysis_R);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            folder = uigetdir(app.fileProps.pathname);
            %             paths = strsplit(app.fileProps.pathname,'\');
            %             measNumber = paths{5};
            filename = strsplit(app.fileProps.filename,'.');
            filename = filename{1};
            paths = fullfile(folder,filename);
            savetomat(paths, app.rData,app.plotProps.titles,...
                app.iData.Fs,app.peakFinder.thresholdDur,app.iData.comment);
        end

        % Button pushed function: ClearvariablesButton
        function ClearvariablesButtonPushed(app, event)
            resetvariables(app);
        end

        % Value changed function: StartTimeSpinner
        function StartTimeSpinnerValueChanged(app, event)
            app.iData.sTime = app.StartTimeSpinner.Value;
            app.iData.eTime = app.iData.sTime + app.PlotdurationSpinner.Value;
            app.plotProps.xlims = [app.iData.sTime app.iData.eTime];
            %             app.XStartSpinner.Limits = app.plotProps.xlims;
            %             app.XEndSpinner.Limits = app.plotProps.xlims;
            %             app.XEndSpinner.Value = app.plotProps.xlims(2);
            disablecontrols(app);
            fetchdata(app);
            enablecontrols(app);
        end

        % Value changed function: PlotdurationSpinner
        function PlotdurationSpinnerValueChanged(app, event)
            app.iData.eTime = app.iData.sTime + app.PlotdurationSpinner.Value;
            app.StartTimeSpinner.Step = app.PlotdurationSpinner.Value;
            app.plotProps.xlims = [app.iData.sTime app.iData.eTime];
%             app.XStartSpinner.Limits = app.plotProps.xlims;
%             app.XEndSpinner.Limits = app.plotProps.xlims;
%             app.XEndSpinner.Value = app.plotProps.xlims(2);
            disablecontrols(app);
            fetchdata(app);
            enablecontrols(app);            
        end

        % Value changed function: nStdSpinner
        function nStdSpinnerValueChanged(app, event)
            app.peakFinder.N = app.nStdSpinner.Value;
            [results, err] = estVar(app.iData,app.peakFinder.N,4,6,app.plotProps.fToggle);
            if ~isempty(err)
                printerror(app, err);
                return
            end
            assignToStruct(app,'peakFinder',results);
            app.Plot1stdDisp.Value = app.peakFinder.sdevplot1;
            app.Plot2stdDisp.Value = app.peakFinder.sdevplot2;            
        end

        % Value changed function: ModenumberDropDown
        function ModenumberDropDownValueChanged(app, event)
            app.iData.modeNumber = str2double(app.ModenumberDropDown.Value);
            plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,...
                [app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
        end

        % Value changed function: PlotdetrendeddataSwitch
        function PlotdetrendeddataSwitchValueChanged(app, event)
            app.plotProps.dToggle = app.PlotdetrendeddataSwitch.Value;
            plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,...
                [app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);           
        end

        % Value changed function: WinsizeSpinner
        function WinsizeSpinnerValueChanged(app, event)
            app.plotProps.winsize = app.WinsizeSpinner.Value;
            if strcmpi(app.plotProps.fToggle,'on')
                [results, err] = detrendData(app.iData,{'fplot1','fplot2'},app.plotProps.winsize);
            else
                [results, err] = detrendData(app.iData,{'plot1','plot2'},app.plotProps.winsize);
            end
            if ~isempty(err)
                printerror(app, err);
                return
            end
            assignToStruct(app, 'iData',results);
            plotdata([app.plot1,app.plot2], app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,...
                [app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
        end

        % Value changed function: PeakDetectionMethodDropDown
        function PeakDetectionMethodDropDownValueChanged(app, event)
            value = app.PeakDetectionMethodDropDown.Value;
            if strcmpi(value,'FindPeaks Matlab')
                app.Plot1GroupWidth.Enable = false;
                app.Plot1SmoothWidth.Enable = false;    
                app.Plot1Smoothtype.Enable = false;
                app.Plot1Slopethreshold.Enable = false;
                app.Plot2GroupWidth.Enable = false;
                app.Plot2SmoothWidth.Enable = false;
                app.Plot2Smoothtype.Enable = false;
                app.Plot2Slopethreshold.Enable = false;
            else
                app.Plot1GroupWidth.Enable = true;
                app.Plot1SmoothWidth.Enable = true;    
                app.Plot1Smoothtype.Enable = true;
                app.Plot1Slopethreshold.Enable = true;
                app.Plot2GroupWidth.Enable = true;
                app.Plot2SmoothWidth.Enable = true;
                app.Plot2Smoothtype.Enable = true;
                app.Plot2Slopethreshold.Enable = true;
            end            
        end

        % Value changed function: FiltertypeDropDown
        function FiltertypeDropDownValueChanged(app, event)
            app.fData.filterType = app.FiltertypeDropDown.Value...
                (~isspace(app.FiltertypeDropDown.Value));            
        end

        % Value changed function: WindowsizeSpinner
        function WindowsizeSpinnerValueChanged(app, event)
            app.fData.winSize = app.WindowsizeSpinner.Value;            
        end

        % Button pushed function: FilterdataButton
        function FilterdataButtonPushed(app, event)
            disablecontrols(app);
            [results,err] = filterdata(app.iData,{'plot1','plot2'},...
                app.fData.filterType,app.fData.winSize);
            if ~isempty(err)
                printerror(app,err);
                return
            end
            assignToStruct(app,'iData',results);
            [results,err] = detrendData(app.iData,{'fplot1','fplot2'});
            if ~isempty(err)
                printerror(app,err);
                return
            end
            assignToStruct(app,'iData',results);
            err = plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,...
                [app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
            nStdSpinnerValueChanged(app, event);
            if ~isempty(err)
                printerror(app,err);
                return
            end
            nStdSpinnerValueChanged(app, event);
            enablecontrols(app);
        end

        % Value changed function: Plotfiltered
        function PlotfilteredValueChanged(app, event)
            app.plotProps.fToggle = app.Plotfiltered.Value;
            nStdSpinnerValueChanged(app, event);
            plotdata([app.plot1,app.plot2], app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,...
                [app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);            
        end

        % Value changed function: Plot1Ampthreshold
        function Plot1AmpthresholdValueChanged(app, event)
            app.peakFinder.ampThresholdplot1 = app.Plot1Ampthreshold.Value;
            err = plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,...
                [app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
            if ~isempty(err)
                printerror(app,err);
                return
            end            
        end

        % Value changed function: Plot1Smoothtype
        function Plot1SmoothtypeValueChanged(app, event)
            app.peakFinder.smoothTypeplot1 = app.Plot1Smoothtype.Value;
        end

        % Value changed function: Plot1SmoothWidth
        function Plot1SmoothWidthValueChanged(app, event)
            app.peakFinder.smoothWidthplot1 = app.Plot1SmoothWidth.Value;            
        end

        % Value changed function: Plot1GroupWidth
        function Plot1GroupWidthValueChanged(app, event)
            app.peakFinder.groupWidthplot1  = app.Plot1GroupWidth.Value;            
        end

        % Value changed function: Plot1Slopethreshold
        function Plot1SlopethresholdValueChanged(app, event)
            app.peakFinder.slopeThresholdplot1 = app.Plot1Slopethreshold.Value;            
        end

        % Value changed function: Plot2Ampthreshold
        function Plot2AmpthresholdValueChanged(app, event)
            app.peakFinder.ampThresholdplot2 = app.Plot2Ampthreshold.Value;
            err = plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,...
                [app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
            if ~isempty(err)
                printerror(app,err);
                return
            end            
        end

        % Value changed function: Plot2Smoothtype
        function Plot2SmoothtypeValueChanged(app, event)
            app.peakFinder.smoothTypeplot2 = app.Plot2Smoothtype.Value;            
        end

        % Value changed function: Plot2SmoothWidth
        function Plot2SmoothWidthValueChanged(app, event)
            app.peakFinder.smoothWidthplot2  = app.Plot2SmoothWidth.Value;           
        end

        % Value changed function: Plot2GroupWidth
        function Plot2GroupWidthValueChanged(app, event)
            app.peakFinder.groupWidthplot2  = app.Plot2GroupWidth.Value;            
        end

        % Value changed function: Plot2Slopethreshold
        function Plot2SlopethresholdValueChanged(app, event)
            app.peakFinder.slopeThresholdplot2 = app.Plot2Slopethreshold.Value;            
        end

        % Value changed function: DurationThresholdSpinner
        function DurationThresholdSpinnerValueChanged(app, event)
            app.peakFinder.thresholdDur = app.DurationThresholdSpinner.Value/1000;
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            disablecontrols(app);
            peakfinder(app);
            enablecontrols(app);
        end

        % Value changed function: NegativepeaksSwitch
        function NegativepeaksSwitchValueChanged(app, event)
            app.peakFinder.nflag = app.NegativepeaksSwitch.Value;           
        end

        % Button pushed function: AddspecialPeaksButton
        function AddspecialPeaksButtonPushed(app, event)
            app.rData.sPeaks1F = [app.rData.sPeaks1F,app.tmp.plot1PeaksF];
            app.rData.sPeaks1F = uniquestruct(app.rData.sPeaks1F);
            app.rData.sPeaks2F = [app.rData.sPeaks2F,app.tmp.plot2PeaksF];
            app.rData.sPeaks2F = uniquestruct(app.rData.sPeaks2F);
        end

        % Button pushed function: ReGrouppeaksButton
        function ReGrouppeaksButtonPushed(app, event)
            fields = fieldnames(app.aData.Peaks);
            names1 = fields(contains(fields,'_1'));
            names2 = fields(contains(fields,'_2'));
            for i = length(names1)
                [app.aData.Peaks.(names2{i}),err] = grouppeaks(app.aData.Peaks.(names1{i}),...
                    app.DurationThresholdSpinner_2.Value);
            end
            if ~isempty(err)
                printerror(app,err);
                return
            end
        end

        % Button pushed function: AddPlot1PeakButton
        function AddPlot1PeakButtonPushed(app, event)
            if isempty(app.rData.plot1Peaks)
                app.rData.plot1Peaks = struct('h',[],'l',[],'w',[],'p',[],'area',[]);
                app.rData.plot1PeaksF = struct([]);
            end
            names = fieldnames(app.tmp.plot1Peaks);
            for i = 1:length(names)
                app.rData.plot1Peaks.(names{i}) = ...
                    [app.rData.plot1Peaks.(names{i});app.tmp.plot1Peaks.(names{i})];
            end
            app.rData.plot1PeaksF = [app.rData.plot1PeaksF,app.tmp.plot1PeaksF];
            app.rData.plot1PeaksF = uniquestruct(app.rData.plot1PeaksF);
            plotpeakinfo(app.peakh, app.rData, {'plot1PeaksF'},'h',[0,0.447,0.741]);
            plotpeakinfo(app.peakd, app.rData,{'plot1PeaksF'},'w',[0,0.447,0.741]);
        end

        % Button pushed function: AddPlot2PeakButton
        function AddPlot2PeakButtonPushed(app, event)
            if isempty(app.rData.plot2Peaks)
                app.rData.plot2Peaks = struct('h',[],'l',[],'w',[],'p',[],'area',[]);
                app.rData.plot2PeaksF = struct([]);
            end
            names = fieldnames(app.tmp.plot2Peaks);
            for i = 1:length(names)
                app.rData.plot2Peaks.(names{i}) =...
                    [app.rData.plot2Peaks.(names{i});app.tmp.plot2Peaks.(names{i})];
            end
            app.rData.plot2PeaksF = [app.rData.plot2PeaksF,app.tmp.plot2PeaksF];
            app.rData.plot2PeaksF = uniquestruct(app.rData.plot2PeaksF);
            plotpeakinfo(app.peakh, app.rData, {'plot2PeaksF'},'h',[0.85 0.325 0.098]);
            plotpeakinfo(app.peakd, app.rData,{'plot2PeaksF'},'w',[0.85 0.325 0.098]);
        end

        % Value changed function: startPointSpinner
        function startPointSpinnerValueChanged(app, event)
            app.stepFinder.startPoint = app.startPointSpinner.Value;
            findsteps(app);           
        end

        % Value changed function: leftOffsetSpinner
        function leftOffsetSpinnerValueChanged(app, event)
            app.stepFinder.leftOffset = app.leftOffsetSpinner.Value;
            findsteps(app);            
        end

        % Value changed function: leftIntervalSpinner
        function leftIntervalSpinnerValueChanged(app, event)
            app.stepFinder.leftInterval = app.leftIntervalSpinner.Value;
            findsteps(app);
        end

        % Value changed function: rightOffsetSpinner
        function rightOffsetSpinnerValueChanged(app, event)
            app.stepFinder.rightOffset = app.rightOffsetSpinner.Value;
            findsteps(app);
        end

        % Value changed function: rightIntervalSpinner
        function rightIntervalSpinnerValueChanged(app, event)
            app.stepFinder.rightInterval = app.rightIntervalSpinner.Value;
            findsteps(app);
        end

        % Button pushed function: AddStepPlot1Button
        function AddStepPlot1ButtonPushed(app, event)
            mN = app.iData.modeNumber;
            numModes = app.iData.numModes;
            if isempty(app.rData.plot1StepsF)
                app.rData.plot1StepsF = struct('t',nan(numModes,mN),'h',...
                    nan(numModes,mN),'w',nan(numModes,mN),'numSteps',zeros(numModes,mN),...
                    'leftOffset',nan(numModes,mN),'rightOffset',nan(numModes,mN));
            end
            if isempty(app.rData.plot2StepsF)
                app.rData.plot2StepsF = struct('t',nan(numModes,mN),'h',...
                    nan(numModes,mN),'w',nan(numModes,mN),'numSteps',zeros(numModes,mN),...
                    'leftOffset',nan(numModes,mN),'rightOffset',nan(numModes,mN));
            end
            app.rData.plot1StepsF.numSteps(mN) = app.rData.plot1StepsF.numSteps(mN) + 1;
            numSteps = app.rData.plot1StepsF.numSteps(mN);
            app.rData.plot1StepsF.t(numSteps,mN) = app.stepFinder.T_plot1;
            app.rData.plot1StepsF.h(numSteps,mN) = app.stepFinder.H_plot1;
            app.rData.plot1StepsF.w(numSteps,mN)  = app.stepFinder.W_plot1;
            app.rData.plot1StepsF.leftOffset(numSteps,mN) = app.leftOffsetSpinner.Value;
            app.rData.plot1StepsF.rightOffset(numSteps,mN) = app.rightOffsetSpinner.Value;
            plot(app.steph, app.rData.plot1StepsF.t,...
                app.rData.plot1StepsF.h,'v','Color',[0,0.447,0.741]);
            hold(app.steph,'on');
            plot(app.steph, app.rData.plot2StepsF.t,...
                app.rData.plot2StepsF.h,'v','Color',[0.85 0.325 0.098]);
            hold(app.steph,'off');
        end

        % Button pushed function: AddStepPlot2Button
        function AddStepPlot2ButtonPushed(app, event)
            numModes = app.iData.numModes;
            if isempty(app.rData.plot1StepsF)
                app.rData.plot1StepsF = struct('t',nan(numModes,mN),'h',...
                    nan(numModes,mN),'w',nan(numModes,mN),'numSteps',zeros(numModes,mN),...
                    'leftOffset',nan(numModes,mN),'rightOffset',nan(numModes,mN));
            end
            if isempty(app.rData.plot2StepsF)
                app.rData.plot2StepsF = struct('t',nan(numModes,mN),'h',...
                    nan(numModes,mN),'w',nan(numModes,mN),'numSteps',zeros(numModes,mN),...
                    'leftOffset',nan(numModes,mN),'rightOffset',nan(numModes,mN));
            end
            mN = app.iData.modeNumber;
            app.rData.plot2StepsF.numSteps(mN) = app.rData.plot2StepsF.numSteps(mN)  +1;
            numSteps = app.rData.plot2StepsF.numSteps(mN) ;
            app.rData.plot2StepsF.t(numSteps,mN) = app.stepFinder.T_plot2;
            app.rData.plot2StepsF.h(numSteps,mN) = app.stepFinder.H_plot2;
            app.rData.plot2StepsF.w(numSteps,mN)  = app.stepFinder.W_plot2;
            app.rData.plot2StepsF.leftOffset(numSteps,mN) = app.leftOffsetSpinner.Value;
            app.rData.plot2StepsF.rightOffset(numSteps,mN) = app.rightOffsetSpinner.Value;
            plot(app.steph, app.rData.plot1StepsF.t,...
                app.rData.plot1StepsF.h,'v','Color',[0,0.447,0.741]);
            hold(app.steph,'on');
            plot(app.steph, app.rData.plot2StepsF.t,...
                app.rData.plot2StepsF.h,'v','Color',[0.85 0.325 0.098]);
            hold(app.steph,'off');
        end

        % Button pushed function: SaveplotsButton
        function SaveplotsButtonPushed(app, event)
            path = fullfile(app.fileProps.pathname,app.fileProps.filename);
            xlabel = 'Time [s]';
            ylabel_1 = '$\Delta \lambda$ [fm]';
            ylabel_2 = '$\Delta \kappa$ [fm]';
            err = saveplots(app.iData,path, app.tmp, app.plotProps.dToggle,...
                app.plotProps.fToggle, xlabel,ylabel_1,ylabel_2, app.plot1.XLim,[app.plot1.YLim;app.plot2.YLim]);
            if ~isempty(err)
                printerror(app, err);
                return
            end
        end

        % Callback function
        function SelectChannelPlot1ValueChanged(app, event)
            app.iData.chNames(1,:) = app.SelectChannelPlot1.Value;
            disablecontrols(app);
            fetchdata(app);
            enablecontrols(app);
        end

        % Callback function
        function SelectChannelPlot2ValueChanged(app, event)
            app.iData.chNames(2,:) = app.SelectChannelPlot2.Value;
            disablecontrols(app);
            fetchdata(app);
            enablecontrols(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DataAnalysis_R.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {745, 745};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {382, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataAnalysis_R = uifigure('Visible', 'off');
            app.DataAnalysis_R.Name = 'UI Figure';
            app.DataAnalysis_R.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DataAnalysis_R);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.DisplayControlPanel = uipanel(app.GridLayout2);
            app.DisplayControlPanel.Title = 'Display Control';
            app.GridLayout5 = uigridlayout(app.DisplayControlPanel);
            app.StartSpinnerLabel = uilabel(app.GridLayout5);
            app.StartSpinnerLabel.Text = 'Start';
            app.StartTimeSpinner = uispinner(app.GridLayout5);
            app.StartTimeSpinner.Limits = [0 Inf];
            app.StartTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @StartTimeSpinnerValueChanged, true);
            app.DurationSpinnerLabel = uilabel(app.GridLayout5);
            app.DurationSpinnerLabel.Text = 'Duration';
            app.PlotdurationSpinner = uispinner(app.GridLayout5);
            app.PlotdurationSpinner.Limits = [0 Inf];
            app.PlotdurationSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotdurationSpinnerValueChanged, true);
            app.PlotdurationSpinner.Value = 20;
            app.StdOverSpinnerLabel = uilabel(app.GridLayout5);
            app.StdOverSpinnerLabel.Text = 'Std Over';
            app.nStdSpinner = uispinner(app.GridLayout5);
            app.nStdSpinner.Limits = [0 Inf];
            app.nStdSpinner.ValueChangedFcn = createCallbackFcn(app, @nStdSpinnerValueChanged, true);
            app.nStdSpinner.Value = 10;
            app.ModenumDropDownLabel = uilabel(app.GridLayout5);
            app.ModenumDropDownLabel.Text = 'Mode num';
            app.ModenumberDropDown = uidropdown(app.GridLayout5);
            app.ModenumberDropDown.Items = {'1'};
            app.ModenumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ModenumberDropDownValueChanged, true);
            app.ModenumberDropDown.Value = '1';
            app.PlotdetrendSwitchLabel = uilabel(app.GridLayout5);
            app.PlotdetrendSwitchLabel.Text = 'Plot detrend?';
            app.PlotdetrendeddataSwitch = uiswitch(app.GridLayout5, 'slider');
            app.PlotdetrendeddataSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotdetrendeddataSwitchValueChanged, true);
            app.WinsizeSpinnerLabel = uilabel(app.GridLayout5);
            app.WinsizeSpinnerLabel.Text = 'Win size';
            app.WinsizeSpinner = uispinner(app.GridLayout5);
            app.WinsizeSpinner.Limits = [5 Inf];
            app.WinsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @WinsizeSpinnerValueChanged, true);
            app.WinsizeSpinner.Value = 101;
            app.PeakAnalysisPanel = uipanel(app.GridLayout2);
            app.PeakAnalysisPanel.Title = 'Peak Analysis';
            app.GridLayout8 = uigridlayout(app.PeakAnalysisPanel);
            app.FilterPropertiesPanel = uipanel(app.GridLayout8);
            app.FilterPropertiesPanel.Title = 'Filter Properties';
            app.GridLayout9 = uigridlayout(app.FilterPropertiesPanel);
            app.FiltertypeLabel = uilabel(app.GridLayout9);
            app.FiltertypeLabel.Text = {'Filter'; 'type'};
            app.FiltertypeDropDown = uidropdown(app.GridLayout9);
            app.FiltertypeDropDown.Items = {'averaging', 'moving average', 'median', 'golay', 'gaussian'};
            app.FiltertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltertypeDropDownValueChanged, true);
            app.FiltertypeDropDown.Value = 'moving average';
            app.WindowSizeSpinnerLabel = uilabel(app.GridLayout9);
            app.WindowSizeSpinnerLabel.Text = {'Window'; 'Size'};
            app.WindowsizeSpinner = uispinner(app.GridLayout9);
            app.WindowsizeSpinner.Limits = [3 Inf];
            app.WindowsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSpinnerValueChanged, true);
            app.WindowsizeSpinner.Value = 101;
            app.FilterdataButton = uibutton(app.GridLayout9, 'push');
            app.FilterdataButton.ButtonPushedFcn = createCallbackFcn(app, @FilterdataButtonPushed, true);
            app.FilterdataButton.Text = {'Filter'; 'data'};
            app.Plotfiltered = uiswitch(app.GridLayout9, 'slider');
            app.Plotfiltered.ValueChangedFcn = createCallbackFcn(app, @PlotfilteredValueChanged, true);
            app.LambdapropertiesPanel = uipanel(app.GridLayout8);
            app.LambdapropertiesPanel.Title = 'Lambda properties';
            app.GridLayout10 = uigridlayout(app.LambdapropertiesPanel);
            app.AmpThreshSpinnerLabel = uilabel(app.GridLayout10);
            app.AmpThreshSpinnerLabel.Text = 'Amp. Thresh.';
            app.Plot1Ampthreshold = uispinner(app.GridLayout10);
            app.Plot1Ampthreshold.Step = 0.1;
            app.Plot1Ampthreshold.Limits = [0 Inf];
            app.Plot1Ampthreshold.ValueChangedFcn = createCallbackFcn(app, @Plot1AmpthresholdValueChanged, true);
            app.Plot1Ampthreshold.Value = 2;
            app.SmoothWiidthSpinnerLabel = uilabel(app.GridLayout10);
            app.SmoothWiidthSpinnerLabel.Text = 'Smooth Wiidth';
            app.Plot1SmoothWidth = uispinner(app.GridLayout10);
            app.Plot1SmoothWidth.Limits = [1 Inf];
            app.Plot1SmoothWidth.ValueChangedFcn = createCallbackFcn(app, @Plot1SmoothWidthValueChanged, true);
            app.Plot1SmoothWidth.Value = 11;
            app.GroupWidthSpinnerLabel = uilabel(app.GridLayout10);
            app.GroupWidthSpinnerLabel.Text = 'Group Width';
            app.Plot1GroupWidth = uispinner(app.GridLayout10);
            app.Plot1GroupWidth.Limits = [1 Inf];
            app.Plot1GroupWidth.ValueChangedFcn = createCallbackFcn(app, @Plot1GroupWidthValueChanged, true);
            app.Plot1GroupWidth.Value = 11;
            app.SlopeThreshSpinnerLabel = uilabel(app.GridLayout10);
            app.SlopeThreshSpinnerLabel.Text = 'Slope Thresh';
            app.Plot1Slopethreshold = uispinner(app.GridLayout10);
            app.Plot1Slopethreshold.Step = 0.0001;
            app.Plot1Slopethreshold.Limits = [0 Inf];
            app.Plot1Slopethreshold.ValueChangedFcn = createCallbackFcn(app, @Plot1SlopethresholdValueChanged, true);
            app.Plot1Slopethreshold.Value = 0.0001;
            app.SmoothtypeDropDownLabel = uilabel(app.GridLayout10);
            app.SmoothtypeDropDownLabel.Text = 'Smooth type';
            app.Plot1Smoothtype = uidropdown(app.GridLayout10);
            app.Plot1Smoothtype.Items = {'1', '2', '3'};
            app.Plot1Smoothtype.ValueChangedFcn = createCallbackFcn(app, @Plot1SmoothtypeValueChanged, true);
            app.Plot1Smoothtype.Value = '1';
            app.StdDeviationEditFieldLabel = uilabel(app.GridLayout10);
            app.StdDeviationEditFieldLabel.Text = 'Std. Deviation';
            app.Plot1stdDisp = uieditfield(app.GridLayout10, 'numeric');
            app.Plot1stdDisp.Editable = 'off';
            app.FWHMpropertiesPanel = uipanel(app.GridLayout8);
            app.FWHMpropertiesPanel.Title = 'FWHM properties';
            app.GridLayout11 = uigridlayout(app.FWHMpropertiesPanel);
            app.StdDeviationEditField_2Label = uilabel(app.GridLayout11);
            app.StdDeviationEditField_2Label.Text = 'Std. Deviation';
            app.Plot2stdDisp = uieditfield(app.GridLayout11, 'numeric');
            app.Plot2stdDisp.Editable = 'off';
            app.AmpThreshSpinner_2Label = uilabel(app.GridLayout11);
            app.AmpThreshSpinner_2Label.Text = 'Amp. Thresh.';
            app.Plot2Ampthreshold = uispinner(app.GridLayout11);
            app.Plot2Ampthreshold.Step = 0.1;
            app.Plot2Ampthreshold.Limits = [0 Inf];
            app.Plot2Ampthreshold.ValueChangedFcn = createCallbackFcn(app, @Plot2AmpthresholdValueChanged, true);
            app.Plot2Ampthreshold.Value = 2;
            app.SmoothtypeDropDown_2Label = uilabel(app.GridLayout11);
            app.SmoothtypeDropDown_2Label.Text = 'Smooth type';
            app.Plot2Smoothtype = uidropdown(app.GridLayout11);
            app.Plot2Smoothtype.Items = {'1', '2', '3'};
            app.Plot2Smoothtype.ValueChangedFcn = createCallbackFcn(app, @Plot2SmoothtypeValueChanged, true);
            app.Plot2Smoothtype.Value = '1';
            app.SmoothWiidthSpinner_2Label = uilabel(app.GridLayout11);
            app.SmoothWiidthSpinner_2Label.Text = 'Smooth Wiidth';
            app.Plot2SmoothWidth = uispinner(app.GridLayout11);
            app.Plot2SmoothWidth.Limits = [1 Inf];
            app.Plot2SmoothWidth.ValueChangedFcn = createCallbackFcn(app, @Plot2SmoothWidthValueChanged, true);
            app.Plot2SmoothWidth.Value = 11;
            app.GroupWidthSpinner_2Label = uilabel(app.GridLayout11);
            app.GroupWidthSpinner_2Label.Text = 'Group Width';
            app.Plot2GroupWidth = uispinner(app.GridLayout11);
            app.Plot2GroupWidth.Limits = [1 Inf];
            app.Plot2GroupWidth.ValueChangedFcn = createCallbackFcn(app, @Plot2GroupWidthValueChanged, true);
            app.Plot2GroupWidth.Value = 11;
            app.SlopeThreshSpinner_2Label = uilabel(app.GridLayout11);
            app.SlopeThreshSpinner_2Label.Text = 'Slope Thresh';
            app.Plot2Slopethreshold = uispinner(app.GridLayout11);
            app.Plot2Slopethreshold.Step = 0.0001;
            app.Plot2Slopethreshold.Limits = [0 Inf];
            app.Plot2Slopethreshold.ValueChangedFcn = createCallbackFcn(app, @Plot2SlopethresholdValueChanged, true);
            app.Plot2Slopethreshold.Value = 0.0001;
            app.FinalizepeaksPanel = uipanel(app.GridLayout8);
            app.FinalizepeaksPanel.Title = 'Finalize peaks';
            app.GridLayout12 = uigridlayout(app.FinalizepeaksPanel);
            app.FindPeaksButton = uibutton(app.GridLayout12, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.AddPlot1PeakButton = uibutton(app.GridLayout12, 'push');
            app.AddPlot1PeakButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlot1PeakButtonPushed, true);
            app.AddPlot1PeakButton.Text = 'Add Plot1';
            app.AddPlot2PeakButton = uibutton(app.GridLayout12, 'push');
            app.AddPlot2PeakButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlot2PeakButtonPushed, true);
            app.AddPlot2PeakButton.Text = 'Add Plot 2';
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.NegativeSwitchLabel = uilabel(app.GridLayout13);
            app.NegativeSwitchLabel.Text = 'Negative ?';
            app.ReGrouppeaksButton = uibutton(app.GridLayout12, 'push');
            app.ReGrouppeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ReGrouppeaksButtonPushed, true);
            app.ReGrouppeaksButton.Text = 'Regroup';
            app.AddspecialPeaksButton = uibutton(app.GridLayout12, 'push');
            app.AddspecialPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AddspecialPeaksButtonPushed, true);
            app.AddspecialPeaksButton.Text = 'Add special';
            app.GroupwithinmsLabel = uilabel(app.GridLayout12);
            app.GroupwithinmsLabel.Text = {'Group within'; ' [ms]'};
            app.NegativepeaksSwitch = uiswitch(app.GridLayout12, 'slider');
            app.NegativepeaksSwitch.ValueChangedFcn = createCallbackFcn(app, @NegativepeaksSwitchValueChanged, true);
            app.DurationThresholdSpinner = uispinner(app.GridLayout12);
            app.DurationThresholdSpinner.Limits = [0 Inf];
            app.DurationThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @DurationThresholdSpinnerValueChanged, true);
            app.DurationThresholdSpinner.Value = 100;
            app.PeakfindingmethodDropDownLabel = uilabel(app.GridLayout8);
            app.PeakfindingmethodDropDownLabel.Text = 'Peak finding method';
            app.PeakDetectionMethodDropDown = uidropdown(app.GridLayout8);
            app.PeakDetectionMethodDropDown.Items = {'FindPeaks Matlab', 'Gaussian Fitting'};
            app.PeakDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PeakDetectionMethodDropDownValueChanged, true);
            app.PeakDetectionMethodDropDown.Value = 'FindPeaks Matlab';
            app.StepDetectionPanel = uipanel(app.GridLayout2);
            app.StepDetectionPanel.Title = 'Step Detection';
            app.GridLayout14 = uigridlayout(app.StepDetectionPanel);
            app.StartSpinner_2Label = uilabel(app.GridLayout14);
            app.StartSpinner_2Label.Text = 'Start ';
            app.startPointSpinner = uispinner(app.GridLayout14);
            app.startPointSpinner.Limits = [0 Inf];
            app.startPointSpinner.ValueChangedFcn = createCallbackFcn(app, @startPointSpinnerValueChanged, true);
            app.startPointSpinner.Value = 100;
            app.LOSpinnerLabel = uilabel(app.GridLayout14);
            app.LOSpinnerLabel.Text = 'L.O';
            app.leftOffsetSpinner = uispinner(app.GridLayout14);
            app.leftOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @leftOffsetSpinnerValueChanged, true);
            app.ROSpinnerLabel = uilabel(app.GridLayout14);
            app.ROSpinnerLabel.Text = 'R.O.';
            app.rightOffsetSpinner = uispinner(app.GridLayout14);
            app.rightOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @rightOffsetSpinnerValueChanged, true);
            app.LILabel = uilabel(app.GridLayout14);
            app.LILabel.Text = 'L.I.';
            app.leftIntervalSpinner = uispinner(app.GridLayout14);
            app.leftIntervalSpinner.Limits = [0 Inf];
            app.leftIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @leftIntervalSpinnerValueChanged, true);
            app.leftIntervalSpinner.Value = 10;
            app.RISpinnerLabel = uilabel(app.GridLayout14);
            app.RISpinnerLabel.Text = 'R.I.';
            app.rightIntervalSpinner = uispinner(app.GridLayout14);
            app.rightIntervalSpinner.Limits = [0 Inf];
            app.rightIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @rightIntervalSpinnerValueChanged, true);
            app.rightIntervalSpinner.Value = 10;
            app.Plot1fmEditFieldLabel = uilabel(app.GridLayout14);
            app.Plot1fmEditFieldLabel.Text = 'Plot1 [fm]';
            app.plot1heightfmEditField = uieditfield(app.GridLayout14, 'numeric');
            app.plot1heightfmEditField.Editable = 'off';
            app.Plot2fmEditFieldLabel = uilabel(app.GridLayout14);
            app.Plot2fmEditFieldLabel.Text = 'Plot2 [fm]';
            app.plot2heightfmEditField = uieditfield(app.GridLayout14, 'numeric');
            app.plot2heightfmEditField.Editable = 'off';
            app.AddStepPlot1Button = uibutton(app.GridLayout14, 'push');
            app.AddStepPlot1Button.ButtonPushedFcn = createCallbackFcn(app, @AddStepPlot1ButtonPushed, true);
            app.AddStepPlot1Button.Text = '+ Plot1';
            app.AddStepPlot2Button = uibutton(app.GridLayout14, 'push');
            app.AddStepPlot2Button.ButtonPushedFcn = createCallbackFcn(app, @AddStepPlot2ButtonPushed, true);
            app.AddStepPlot2Button.Text = '+Plot2';
            app.FIleIOPanel = uipanel(app.GridLayout2);
            app.FIleIOPanel.Title = 'FIle I/O';
            app.GridLayout4 = uigridlayout(app.FIleIOPanel);
            app.OpenButton = uibutton(app.GridLayout4, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'File open';
            app.ExitButton = uibutton(app.GridLayout4, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ClearvariablesButton = uibutton(app.GridLayout4, 'push');
            app.ClearvariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearvariablesButtonPushed, true);
            app.ClearvariablesButton.Text = 'Clear variables';
            app.SaveDataButton = uibutton(app.GridLayout4, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save data';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.FilepathTextAreaLabel = uilabel(app.GridLayout6);
            app.FilepathTextAreaLabel.Text = 'File path';
            app.FilepathTextArea = uitextarea(app.GridLayout6);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.CommentTextAreaLabel = uilabel(app.GridLayout7);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.GridLayout7);
            app.GridLayout15 = uigridlayout(app.GridLayout3);
            app.SaveplotsButton = uibutton(app.GridLayout15, 'push');
            app.SaveplotsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotsButtonPushed, true);
            app.SaveplotsButton.Text = 'Save plots';
            app.plot2 = uiaxes(app.GridLayout3);
            title(app.plot2, '\kappa')
            xlabel(app.plot2, 'Time [s]')
            ylabel(app.plot2, '\kappa')
            app.plot1 = uiaxes(app.GridLayout3);
            title(app.plot1, '\lambda')
            xlabel(app.plot1, 'Time [s]')
            ylabel(app.plot1, '\Delta \lambda')
            app.steph = uiaxes(app.GridLayout3);
            title(app.steph, 'Step Height')
            xlabel(app.steph, 'Time [s]')
            ylabel(app.steph, 'Height [fm]')
            app.peakd = uiaxes(app.GridLayout3);
            title(app.peakd, 'Peak duration')
            xlabel(app.peakd, 'Time [s]')
            ylabel(app.peakd, 'Duration [s]')
            app.peakh = uiaxes(app.GridLayout3);
            title(app.peakh, 'Peak height')
            xlabel(app.peakh, 'Time [s]')
            ylabel(app.peakh, 'Height [fm]')
            % Show the figure after all components are created
            app.DataAnalysis_R.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataAnalysis_R)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataAnalysis_R)
        end
    end
end